classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LowFreqSliderLabel   matlab.ui.control.Label
        LowFreqSlider        matlab.ui.control.Slider
        LoadFileButton       matlab.ui.control.Button
        PlayButton           matlab.ui.control.Button
        WindowDropDownLabel  matlab.ui.control.Label
        WindowDropDown       matlab.ui.control.DropDown
        MedFreqSliderLabel   matlab.ui.control.Label
        MedFreqSlider        matlab.ui.control.Slider
        HighFreqSliderLabel  matlab.ui.control.Label
        HighFreqSlider       matlab.ui.control.Slider
        Senal                matlab.ui.control.UIAxes
        Fourier              matlab.ui.control.UIAxes
        Senal_2              matlab.ui.control.UIAxes
        Fourier_2            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function RecalculateOutput(app)
            
            Fxs = getappdata(app.PlayButton,'Fxs');
            f = getappdata(app.PlayButton,'f');
            fs = getappdata(app.PlayButton,'fs');
            
            if isempty(Fxs)
                return
            end
            
            low_scaler = 10^(app.LowFreqSlider.Value);
            med_scaler = 10^(app.MedFreqSlider.Value);
            high_scaler = 10^(app.HighFreqSlider.Value);
               
            %%%% modificar segun sliders (on change de slider/ rectangle, actualizar)
            if strcmpi(app.WindowDropDown.Value,'Rectangular')
                
               window1_end = floor((fs/2)/8);
            
               window3_start = floor(fs/2 - (fs/2)/8);
            
               amp1 = amplify_frequency(Fxs,f,1,window1_end,low_scaler);
               amp2 = amplify_frequency(Fxs,f,window1_end + 1, window3_start,med_scaler);
               amp3 = amplify_frequency(Fxs,f,window3_start,floor(fs/2)-1,high_scaler);
               
            else
               window1_end = floor((fs/2)/8);
               window3_start = floor(fs/2 - (fs/2)/8);
               
               overlap1_end = window1_end + floor(fs/2/16);
               overlap2_start = window1_end - floor(fs/2/16);
               overlap2_end = window3_start + floor(fs/2/16);
               overlap3_start = window3_start - floor(fs/2/16);
               
               amp1 = amplify_frequency(Fxs,f,1,overlap1_end,low_scaler);
               amp2 = amplify_frequency(Fxs,f,overlap2_start, overlap2_end,med_scaler);
               amp3 = amplify_frequency(Fxs,f,overlap3_start,floor(fs/2)-1,high_scaler);
            end
            
            fourier_output = amp1 + amp2 + amp3;
            Fx2 = ifftshift(fourier_output); % Devolviendo el corrimiento realizado con fftshift
            y = real(ifft(Fx2));
            
            plot(app.Senal_2,y,"Color",[8, 98, 150]./255)
            plot(app.Fourier_2,f,abs(fourier_output),"Color",[8, 98, 150]./255)
            y = y./max(y); % Se normaliza la señal de salida
            
            setappdata(app.PlayButton,'output',y)
        end
        
        function RecalculateWindows(app)
            fs = getappdata(app.PlayButton,'fs');
            if isempty(fs)
                return
            end
            if strcmpi(app.WindowDropDown.Value,'Rectangular')
                
               window1_end = floor((fs/2)/8);
               window3_start = floor(fs/2 - (fs/2)/8);
               app.LowFreqSliderLabel.Text = sprintf('(0-%0.1f)kHz',window1_end/1000);
               app.MedFreqSliderLabel.Text = sprintf('(%0.1f-%0.1f)kHz',window1_end/1000,window3_start/1000);
               app.HighFreqSliderLabel.Text = sprintf('(%0.1f-%0.1f)kHz',window3_start/1000, fs/2/1000);
               
            else
               window1_end = floor((fs/2)/8);
               window3_start = floor(fs/2 - (fs/2)/8);
               
               overlap1_end = window1_end + floor(fs/2/16);
               overlap2_start = window1_end - floor(fs/2/16);
               overlap2_end = window3_start + floor(fs/2/16);
               overlap3_start = window3_start - floor(fs/2/16);
               
               app.LowFreqSliderLabel.Text = sprintf('(0-%0.1f)kHz',overlap1_end/1000);
               app.MedFreqSliderLabel.Text = sprintf('(%0.1f-%0.1f)kHz',overlap2_start/1000,overlap2_end/1000);
               app.HighFreqSliderLabel.Text = sprintf('(%0.1f-%0.1f)kHz',overlap3_start/1000, fs/2/1000);
               
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            %[filepath, parentpath] = uigetfile({'*.wav','*.mp3'});
            

            [filepath,parentpath] = uigetfile({'*.wav;*.mp3;*.ogg;*.flac;*.au;*.aiff;*.aif;*.aifc;*.m4a;*.mp4;',...
                'Audio Files (*.wav,*.mp3,*.flac,*.au,*.aiff,*.aif,*.aifc,*.m4a,*.mp4)';
               '*.*',  'All Files (*.*)'}, ...
               'Select a File');
             
            if filepath == 0
                return
            end
            [musicvector,fs] = audioread(strcat(parentpath , filepath));
            
            setappdata(app.PlayButton,'musicvector',musicvector)
            setappdata(app.PlayButton,'fs',fs)
            
            normalized = musicvector/max(musicvector);
            
            n = length(normalized);
            Fx = fft(normalized); % Algoritmo para calcular los valores de la Transformada de Fourier
            Fxs = fftshift(Fx); % Corrimiento de la transformada para obtener valores negativos y positivos. 
            f = (-n/2:n/2-1)*fs/n; % Creación del vector de Frecuencias
            
            plot(app.Senal,musicvector,"Color",[0 0.7 0.9]);
            plot(app.Fourier,f,abs(Fxs),"Color",[0 0.7 0.9]);
            
            setappdata(app.PlayButton,'f',f);
            setappdata(app.PlayButton,'Fxs',Fxs);
            
            RecalculateWindows(app)
            RecalculateOutput(app)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            x = getappdata(app.PlayButton,'output');
            if isempty(x)
                msgbox("No file has been loaded yet!")
            else
                clear sound
                fs = getappdata(app.PlayButton,'fs');
                sound(x,fs);
            end
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            RecalculateWindows(app);
            RecalculateOutput(app);
        end

        % Value changed function: HighFreqSlider, LowFreqSlider, 
        % MedFreqSlider
        function LowFreqSliderValueChanged(app, event)
            clear sound
            RecalculateOutput(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LowFreqSliderLabel = uilabel(app.UIFigure);
            app.LowFreqSliderLabel.Text = 'Low Freq';
            app.LowFreqSlider = uislider(app.UIFigure);
            app.LowFreqSlider.Limits = [-4 4];
            app.LowFreqSlider.MajorTicks = [-4 -3 -2 -1 0 1 2 3 4];
            app.LowFreqSlider.MajorTickLabels = {'10e-4', '10e-3', '10e-2', '10e-1', '1', '10e1', '10e2', '10e3', '10e4'};
            app.LowFreqSlider.ValueChangedFcn = createCallbackFcn(app, @LowFreqSliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play!';
            app.WindowDropDownLabel = uilabel(app.UIFigure);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.UIFigure);
            app.WindowDropDown.Items = {'Rectangular', 'Hanning'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Value = 'Rectangular';
            app.MedFreqSliderLabel = uilabel(app.UIFigure);
            app.MedFreqSliderLabel.Text = 'Med Freq';
            app.MedFreqSlider = uislider(app.UIFigure);
            app.MedFreqSlider.Limits = [-4 4];
            app.MedFreqSlider.MajorTicks = [-4 -3 -2 -1 0 1 2 3 4];
            app.MedFreqSlider.MajorTickLabels = {'10e-4', '10e-3', '10e-2', '10e-1', '1', '10e1', '10e2', '10e3', '10e4'};
            app.MedFreqSlider.ValueChangedFcn = createCallbackFcn(app, @LowFreqSliderValueChanged, true);
            app.HighFreqSliderLabel = uilabel(app.UIFigure);
            app.HighFreqSliderLabel.Text = 'High Freq';
            app.HighFreqSlider = uislider(app.UIFigure);
            app.HighFreqSlider.Limits = [-4 4];
            app.HighFreqSlider.MajorTicks = [-4 -3 -2 -1 0 1 2 3 4];
            app.HighFreqSlider.MajorTickLabels = {'10e-4', '10e-3', '10e-2', '10e-1', '1', '10e1', '10e2', '10e3', '10e4'};
            app.HighFreqSlider.ValueChangedFcn = createCallbackFcn(app, @LowFreqSliderValueChanged, true);
            app.Senal = uiaxes(app.UIFigure);
            title(app.Senal, 'Señal')
            xlabel(app.Senal, 'Tiempo')
            ylabel(app.Senal, 'Amplitud')
            zlabel(app.Senal, 'Z')
            app.Fourier = uiaxes(app.UIFigure);
            title(app.Fourier, 'Espectro de Frecuencias')
            xlabel(app.Fourier, 'Frecuencia')
            ylabel(app.Fourier, 'Amplitud')
            zlabel(app.Fourier, 'Z')
            app.Senal_2 = uiaxes(app.UIFigure);
            title(app.Senal_2, 'Señal (output)')
            xlabel(app.Senal_2, 'Tiempo')
            ylabel(app.Senal_2, 'Amplitud')
            zlabel(app.Senal_2, 'Z')
            app.Fourier_2 = uiaxes(app.UIFigure);
            title(app.Fourier_2, 'Espectro de Frecuencias (output)')
            xlabel(app.Fourier_2, 'Frecuencia')
            ylabel(app.Fourier_2, 'Amplitud')
            zlabel(app.Fourier_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end